classdef Video_Player_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        axes1                      matlab.ui.control.UIAxes
        text5                      matlab.ui.control.Label
        text1                      matlab.ui.control.Label
        BrowseButton               matlab.ui.control.Button
        filenameindicator          matlab.ui.control.EditField
        PlayButton                 matlab.ui.control.Button
        ExitButton                 matlab.ui.control.Button
        framelabel                 matlab.ui.control.Label
        currentframeindicator      matlab.ui.control.Label
        totalframeindictator       matlab.ui.control.Label
        Forward                    matlab.ui.control.Button
        Backward                   matlab.ui.control.Button
        Check_cricket_speed        matlab.ui.control.Button
        ReversePlay                matlab.ui.control.Button
        pupilfix                   matlab.ui.control.Button
        skipnext                   matlab.ui.control.Button
        reapplyPupilFit            matlab.ui.control.Button
        SpeedSliderLabel           matlab.ui.control.Label
        SpeedSlider                matlab.ui.control.Slider
        JumptoFrameEditFieldLabel  matlab.ui.control.Label
        JumptoFrame                matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        videoObject % VideoReader object
        video_file_path=pwd; % file path for the movie (also saves most recent)
        frameCount % keeps track of current frame
        Running %keeps track of whether we're playing or paused
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Video_Player_OpeningFcn(app, varargin)
            
            
            app.ExitButton.Enable=1;
        end

        % Button pushed function: Backward
        function Backward_Callback(app, event)
            
            app.frameCount=app.frameCount-1;
            
            % Display frames
            app.currentframeindicator.Text=num2str(app.frameCount);
            frame = read(app.videoObject,app.frameCount);
            imshow(frame, 'Parent', app.axes1);
            drawnow;
            
        end

        % Button pushed function: Check_cricket_speed
        function Check_cricket_speed_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Check_cricket_speed (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            input_video_file=app.filenameindicator.Text;
            if strfind(input_video_file, 'raw')
                datafile=strrep(input_video_file, 'raw', 'data');
            elseif  strfind(input_video_file, 'MT')
                datafile=strrep(input_video_file, 'MT', 'data');
            else error('data file not found')
            end
            out=LoadBonsaiTracks(datafile);
            cricketxy=out.cricketxy;
            %cricketxy=cricketxy(start_frame:stop_frame,:);
            cspeed=sqrt(diff(cricketxy(:,1)).^2 + diff(cricketxy(:,2)).^2);
            figure
            plot(cspeed)
            xlabel('frames')
            ylabel('cricket speed, px/s')
            title('cricket speed')
        end

        % Button pushed function: Forward
        function Forward_Callback(app, event)
            app.frameCount=app.frameCount+1;
            
            % Display frames
            app.currentframeindicator.Text=num2str(app.frameCount);
            frame = read(app.videoObject,app.frameCount);
            imshow(frame, 'Parent', app.axes1);
            drawnow;
            
        end

        % Callback function
        function JumpToFrameold_Callback(app, event)
            
            app.frameCount=str2num(app.JumpToFrameold.Value);
            if app.frameCount>app.videoObject.NumberOfFrames
                app.frameCount=app.videoObject.NumberOfFrames;
            elseif app.frameCount<1
                app.frameCount=1;
            end
            % Display frames
            app.currentframeindicator.Text=num2str(app.frameCount);
            frame = read(app.videoObject,app.frameCount);
            imshow(frame, 'Parent', app.axes1);
            drawnow;
            
        end

        % Button pushed function: ReversePlay
        function ReversePlay_Callback(app, event)
            
            if(strcmp(app.ReversePlay.Text,'Pause'))
                app.ReversePlay.Text='Reverse Play';
                app.Running=0;
            else
                app.ReversePlay.Text='Pause';
                %play
                app.Running=1;
                while app.Running
                    app.frameCount=app.frameCount-1;
                    if app.SpeedSlider.Value>1
                        app.frameCount=app.frameCount-round(app.SpeedSlider.Value.^2);
                    elseif app.SpeedSlider.Value<1
                        pause(1/app.SpeedSlider.Value-1)
                    end
                    if app.frameCount>1
                        app.currentframeindicator.Text=num2str(app.frameCount);
                        frame = read(app.videoObject,app.frameCount);
                        imshow(frame, 'Parent', app.axes1);
                        app.frameCount=app.frameCount;
                        drawnow;
                        if(strcmp(app.ReversePlay.Text,'Reverse Play'))
                            app.Running=0;
                        end
                    else
                        app.Running=0;
                        app.ReversePlay.Text='Reverse Play';
                    end
                end
            end
        end

        % Button pushed function: pupilfix
        function pupilfix_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pupilfix (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            name=['fixed_pupil_fit_',app.videoObject.name, '.mat'];
            try
                load(name); % load previous frames
            catch
                fixed_pupil1=[];
            end
            points=ginput;
            
            fitted_points = fit_ellipse(points(:,1), points(:,2), app.axes1); %fitted pupil from gived points (should be at least five)
            fixed_pupil = [app.frameCount fitted_points.X0_in fitted_points.Y0_in fitted_points.long_axis/2 fitted_points.short_axis/2 fitted_points.phi]; %get info from struct to matrix
            
            
            fixed_pupil1=[fixed_pupil1; fixed_pupil]; %add new frame fit [ frame# x y long short]
            
            save(name,'fixed_pupil1') %save (there should be a better way of accumulating new fits and saving them but i have not thought of it
        end

        % Button pushed function: BrowseButton
        function BrowseButton_Callback(app, event)
            %Browse
            
            %you can hardcode a path here for convenience
            %datapath= pwd;
            cd(app.video_file_path)
            
            % there are a bunch of attempts here to force uigetfile
            % to the foreground and then return the app to focus after
            % you've selected the file. These are workarounds for glitchy app focus.
            commandwindow();
            f=figure;
            set(f, 'Position', app.figure1.Position)
            drawnow
            f.Visible='off';
            app.figure1.Visible='off';
            [ video_file_name,app.video_file_path ] = uigetfile({'*'},'Pick a video file');      %;*.png;*.yuv;*.bmp;*.tif'},'Pick a file');
            if(app.video_file_path == 0)
                delete f;
                app.figure1.Visible='on';
                app.video_file_path=pwd;
                return;
            end
            delete(f);
            figure(app.figure1) %return focus to app
            input_video_file = [app.video_file_path,video_file_name];
            app.filenameindicator.Value=input_video_file;
            app.video_file_path=app.video_file_path;
            % Acquiring video
            try
                app.videoObject = VideoReader(input_video_file);
            catch
                warndlg('could not read that video file')
                return
            end
            % Display first frame
            frame_1 = read(app.videoObject,1);
            imshow(frame_1, 'Parent', app.axes1);
            drawnow;
            axis(app.axes1,'off');
            % Display Frame Number
            app.currentframeindicator.Text='1';
            app.totalframeindictator.Text=['  /  ',num2str(app.videoObject.NumberOfFrames)];
            app.framelabel.Visible=1;
            app.currentframeindicator.Visible=1;
            app.totalframeindictator.Visible=1;
            
            app.JumptoFrame.Limits=[1,app.videoObject.NumberOfFrames ]
            app.PlayButton.Enable=1; %pause/play
            app.frameCount=1;
            app.Running=1;
            
        end

        % Button pushed function: PlayButton
        function PlayButton_Callback(app, event)
            %Play button
            
            if(strcmp(app.PlayButton.Text,'Pause'))
                app.PlayButton.Text='Play';
                app.Running=0;
            else
                app.PlayButton.Text='Pause';
                %play
                app.Running=1;
                while app.Running
                    if app.videoObject.CurrentTime < app.videoObject.Duration
                        app.frameCount=app.frameCount+1;
                        
                        if app.SpeedSlider.Value>1
                            app.frameCount=app.frameCount+round(app.SpeedSlider.Value.^2);
                        elseif app.SpeedSlider.Value<1
                            pause(1/app.SpeedSlider.Value-1)
                        end
                        app.currentframeindicator.Text=num2str(app.frameCount);
                        frame = read(app.videoObject,app.frameCount);
                        imshow(frame, 'Parent', app.axes1);
                        app.frameCount=app.frameCount;
                        drawnow;
                        if(strcmp(app.PlayButton.Text,'Play'))
                            app.Running=0;
                        end
                    else
                        app.Running=0;
                        app.PlayButton.Text='Play';
                    end
                end
            end
        end

        % Button pushed function: ExitButton
        function ExitButton_Callback(app, event)
            %Exit button
            
            delete(app.figure1);
        end

        % Button pushed function: reapplyPupilFit
        function reapplyPupilFit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to reapplyPupilFit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            name=['fixed_pupil_fit_',app.videoObject.name, '.mat'];
            
            try
                load(name); % load previous frames
            catch
                fixed_pupil1=[];
            end
            fixed_pupil=[];
            last_fit_frame=fixed_pupil1(end,1);
            num_frames=app.frameCount-last_fit_frame;
            for i=1:num_frames
                fixed_pupil=[fixed_pupil; last_fit_frame+1 fixed_pupil1(end, 2:end)];
                last_fit_frame=fixed_pupil(end,1);
            end
            fixed_pupil1=[fixed_pupil1; fixed_pupil];
            save(name,'fixed_pupil1')
        end

        % Button pushed function: skipnext
        function skipnext_Callback(app, event)
            %Skip next 10 frames
            
            skipFrames=10; %you could change the skip size if you want
            
            app.frameCount= app.frameCount+skipFrames;
            
            % Display frames
            app.currentframeindicator.Text=num2str(app.frameCount);
            frame = read(app.videoObject,app.frameCount);
            imshow(frame, 'Parent', app.axes1);
            drawnow; hold on
            try
                name=['fixed_pupil_fit_',app.videoObject.name, '.mat'];
                load(name);
                L=length(fixed_pupil1);
                ellipse(fixed_pupil1(L,4), fixed_pupil1(L,5), fixed_pupil1(L,6), fixed_pupil1(L,2),fixed_pupil1(L,3), 'b')
                drawnow
                hold off
            end
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            value = app.SpeedSlider.Value;
            if value<.1
                app.SpeedSlider.Value=.1;
            elseif value>.9 & value<1.1
                app.SpeedSlider.Value=1;
            end
        end

        % Value changed function: JumptoFrame
        function JumptoFrameValueChanged(app, event)
            app.frameCount = app.JumptoFrame.Value;
            if app.frameCount>app.videoObject.NumberOfFrames
                app.frameCount=app.videoObject.NumberOfFrames;
            elseif app.frameCount<1
                app.frameCount=1;
            end
            % Display frames
            app.currentframeindicator.Text=num2str(app.frameCount);
            frame = read(app.videoObject,app.frameCount);
            imshow(frame, 'Parent', app.axes1);
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Video_Player';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Visible = 'off';
            app.axes1.Tag = 'axes1';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Frame-by-Frame';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Video Player';
            app.BrowseButton = uibutton(app.figure1, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_Callback, true);
            app.BrowseButton.Tag = 'pushbutton1';
            app.BrowseButton.Text = 'Browse';
            app.filenameindicator = uieditfield(app.figure1, 'text');
            app.filenameindicator.Tag = 'edit1';
            app.filenameindicator.Enable = 'off';
            app.filenameindicator.Value = '     Browse Video File...';
            app.PlayButton = uibutton(app.figure1, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_Callback, true);
            app.PlayButton.Tag = 'pushbutton3';
            app.PlayButton.Text = 'Play';
            app.ExitButton = uibutton(app.figure1, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButton_Callback, true);
            app.ExitButton.Tag = 'pushbutton4';
            app.ExitButton.Text = 'Exit';
            app.framelabel = uilabel(app.figure1);
            app.framelabel.Tag = 'text2';
            app.framelabel.Text = 'Frame : ';
            app.currentframeindicator = uilabel(app.figure1);
            app.currentframeindicator.Tag = 'text3';
            app.currentframeindicator.Text = '1';
            app.totalframeindictator = uilabel(app.figure1);
            app.totalframeindictator.Tag = 'text4';
            app.totalframeindictator.Text = '/  100';
            app.Forward = uibutton(app.figure1, 'push');
            app.Forward.ButtonPushedFcn = createCallbackFcn(app, @Forward_Callback, true);
            app.Forward.Tag = 'Forward';
            app.Forward.Text = '>';
            app.Backward = uibutton(app.figure1, 'push');
            app.Backward.ButtonPushedFcn = createCallbackFcn(app, @Backward_Callback, true);
            app.Backward.Tag = 'Backward';
            app.Backward.Text = '<';
            app.Check_cricket_speed = uibutton(app.figure1, 'push');
            app.Check_cricket_speed.ButtonPushedFcn = createCallbackFcn(app, @Check_cricket_speed_Callback, true);
            app.Check_cricket_speed.Tag = 'Check_cricket_speed';
            app.Check_cricket_speed.Text = 'Check cricket speed';
            app.ReversePlay = uibutton(app.figure1, 'push');
            app.ReversePlay.ButtonPushedFcn = createCallbackFcn(app, @ReversePlay_Callback, true);
            app.ReversePlay.Tag = 'ReversePlay';
            app.ReversePlay.Text = 'Reverse Play';
            app.pupilfix = uibutton(app.figure1, 'push');
            app.pupilfix.ButtonPushedFcn = createCallbackFcn(app, @pupilfix_Callback, true);
            app.pupilfix.Tag = 'pupilfix';
            app.pupilfix.Text = 'pupil fix';
            app.skipnext = uibutton(app.figure1, 'push');
            app.skipnext.ButtonPushedFcn = createCallbackFcn(app, @skipnext_Callback, true);
            app.skipnext.Tag = 'skipnext';
            app.skipnext.Text = 'Skip next 10 frames';
            app.reapplyPupilFit = uibutton(app.figure1, 'push');
            app.reapplyPupilFit.ButtonPushedFcn = createCallbackFcn(app, @reapplyPupilFit_Callback, true);
            app.reapplyPupilFit.Tag = 'reapplyPupilFit';
            app.reapplyPupilFit.Text = 'reapply pupil fit';
            app.SpeedSliderLabel = uilabel(app.figure1);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.figure1);
            app.SpeedSlider.Limits = [0 2];
            app.SpeedSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.Value = 1;
            app.JumptoFrameEditFieldLabel = uilabel(app.figure1);
            app.JumptoFrameEditFieldLabel.Text = 'Jump to Frame #';
            app.JumptoFrame = uieditfield(app.figure1, 'numeric');
            app.JumptoFrame.RoundFractionalValues = 'on';
            app.JumptoFrame.ValueDisplayFormat = '%d';
            app.JumptoFrame.ValueChangedFcn = createCallbackFcn(app, @JumptoFrameValueChanged, true);
            app.JumptoFrame.Value = 1;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Video_Player_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Video_Player_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end